classdef ModelSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADModelSelectionUIFigure  matlab.ui.Figure
        ModelLabel                  matlab.ui.control.Label
        ModelDropDown               matlab.ui.control.DropDown
        CloseButton                 matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        % The app that called this app
        callingApp

        % Struct contatining model configs (parameters)
        modelConfigs = struct();

        % Custom components which are created by code
        customComponentsConfigurable = struct();
        customComponentsStatic = struct();
    end
    
    methods (Access = private)
        function initModelDropDown(app)
            %INITMODELDROPDOWN Adds all models to the model dropdown

            models = fieldnames(app.modelConfigs);
            modelNames = cell(1, numel(models));
            modelClassNames = cell(1, numel(models));

            for i = 1:numel(models)
                modelNames{i} = app.modelConfigs.(models{i}).parameters.static.name;
                modelClassNames{i} = app.modelConfigs.(models{i}).parameters.static.className;
            end

            app.ModelDropDown.Items = modelNames;
            app.ModelDropDown.ItemsData = modelClassNames;
        end

        function showSelectedModelUI(app)
            %SHOWSELECTEDMODELUI Displays all parameter edit fields for the
            %currently selected model

            app.removeAllComponents();

            componentCounter = 0;

            selectedModel = app.ModelDropDown.Value;
            parameters = app.modelConfigs.(selectedModel).parameters;

            if isfield(parameters, "static")
                staticParameters = parameters.static;
                staticParameterNames = fieldnames(staticParameters);

                for i = 1:numel(staticParameterNames)
                    name = staticParameterNames{i};
                    value = staticParameters.(name);               
                    app.addStaticComponent(name, value, componentCounter);
                    componentCounter = componentCounter + 1;
                end
            end

            % Increment component counter to add gap between static and
            % configurable parameters
            componentCounter = componentCounter + 1;

            if isfield(parameters, "configurable")
                configurableParameter = parameters.configurable;
                configurableParameterNames = fieldnames(configurableParameter);

                for i = 1:numel(configurableParameterNames)
                    name = configurableParameterNames{i};
                    value = configurableParameter.(name);               
                    app.addConfigurableComponent(name, value, componentCounter);
                    componentCounter = componentCounter + 1;
                end
            end
        end

        function addStaticComponent(app, name, value, componentCounter)
            %ADDSTATICCOMPONENT Adds a static component according to its
            %type

            nameComponentPosition = [20, 600 - 30 * componentCounter, 190, 22];
            valueComponentPosition = [220, 600 - 30 * componentCounter, 360, 22];
    
            componentID = sprintf("Component_%d", componentCounter);
            
            % Create Name Label
            nameComponent = uilabel(app.TSADModelSelectionUIFigure);
            nameComponent.Position = nameComponentPosition;
            nameComponent.Text = name;

            % Create Non-editable EditField
            valueComponent = uieditfield(app.TSADModelSelectionUIFigure);
            valueComponent.Position = valueComponentPosition;
            valueComponent.Value = value;
            valueComponent.Editable = false;
            valueComponent.BackgroundColor = [0.95 0.95 0.95];

            app.customComponentsStatic.(componentID).nameComponent = nameComponent;
            app.customComponentsStatic.(componentID).valueComponent = valueComponent;
        end

        function addConfigurableComponent(app, name, value, componentCounter)
            %ADDCONFIGURABLECOMPONENT Adds a configurable component
            %according to its type

            nameComponentPosition = [20, 600 - 30 * componentCounter, 190, 22];
            valueComponentPosition = [220, 600 - 30 * componentCounter, 360, 22];
    
            componentID = sprintf("Component_%d", componentCounter);
            
    
            switch value.type
                case "categorical"
                    % Create Label
                    nameComponent = uilabel(app.TSADModelSelectionUIFigure);
                    nameComponent.Position = nameComponentPosition;
                    nameComponent.Text = name;
    
                    % Create DropDown
                    valueComponent = uidropdown(app.TSADModelSelectionUIFigure);
                    valueComponent.Items = string(value.value);
                    valueComponent.ItemsData = value.value;
                    valueComponent.Position = valueComponentPosition;
                    valueComponent.Value = value.initialValue;
                case "integer"
                    % Create Label
                    nameComponent = uilabel(app.TSADModelSelectionUIFigure);
                    nameComponent.Position = nameComponentPosition;
                    nameComponent.Text = name;
    
                    % Create EditField
                    valueComponent = uieditfield(app.TSADModelSelectionUIFigure, "numeric");
                    valueComponent.Limits = value.value;
                    valueComponent.Position = valueComponentPosition;
                    valueComponent.Value = value.initialValue;
                    valueComponent.RoundFractionalValues = true;
                    valueComponent.HorizontalAlignment = "left";
                case "real"
                    % Create Label
                    nameComponent = uilabel(app.TSADModelSelectionUIFigure);
                    nameComponent.Position = nameComponentPosition;
                    nameComponent.Text = name;
    
                    % Create EditField
                    valueComponent = uieditfield(app.TSADModelSelectionUIFigure, "numeric");
                    valueComponent.Limits = value.value;
                    valueComponent.Position = valueComponentPosition;
                    valueComponent.Value = value.initialValue;
                    valueComponent.RoundFractionalValues = false;
                    valueComponent.HorizontalAlignment = "left";
            end

            app.customComponentsConfigurable.(componentID).nameComponent = nameComponent;
            app.customComponentsConfigurable.(componentID).valueComponent = valueComponent;
        end

        function removeAllComponents(app)
            componentIDsStatic = fieldnames(app.customComponentsStatic);
            for i = 1:numel(componentIDsStatic)
                delete(app.customComponentsStatic.(componentIDsStatic{i}).nameComponent);
                delete(app.customComponentsStatic.(componentIDsStatic{i}).valueComponent);
            end

            app.customComponentsStatic = struct();

            componentIDsConfigurable = fieldnames(app.customComponentsConfigurable);
            for i = 1:numel(componentIDsConfigurable)
                delete(app.customComponentsConfigurable.(componentIDsConfigurable{i}).nameComponent);
                delete(app.customComponentsConfigurable.(componentIDsConfigurable{i}).valueComponent);
            end

            app.customComponentsConfigurable = struct();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;

            % Load TSADConfig_models
            app.modelConfigs = readJSONToStruct("TSADConfig_models.json");

            app.initModelDropDown();
            app.showSelectedModelUI();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            currentModel = app.ModelDropDown.Value;

            % First load static parameters
            parameters = app.modelConfigs.(currentModel).parameters.static;            
            
            % Then get all configurable parameters
            fields = fieldnames(app.customComponentsConfigurable);
            for i = 1:numel(fields)
                nameComponent = app.customComponentsConfigurable.(fields{i}).nameComponent.Text;
                parameters.(nameComponent) = app.customComponentsConfigurable.(fields{i}).valueComponent.Value;
            end            

            app.callingApp.addModel(parameters);
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            app.showSelectedModelUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADModelSelectionUIFigure = uifigure('Visible', 'off');
            app.TSADModelSelectionUIFigure.Name = 'TSAD - Model Selection';
            app.TSADModelSelectionUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.AddButton = uibutton(app.TSADModelSelectionUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.CloseButton = uibutton(app.TSADModelSelectionUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ModelDropDown = uidropdown(app.TSADModelSelectionUIFigure);
            app.ModelDropDown.Items = {};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = {};
            app.ModelLabel = uilabel(app.TSADModelSelectionUIFigure);
            app.ModelLabel.Text = 'Model';
            % Show the figure after all components are created
            app.TSADModelSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelSelection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADModelSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TSADModelSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADModelSelectionUIFigure)
        end
    end
end